classdef FITNESSTRACKER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FitnessTrackerwithMATLAB        matlab.ui.Figure
        TrackYourFitnesswithMATLABLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Panel_6                         matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        CheckYourHealthButton           matlab.ui.control.Button
        TrackYourFitnessButton          matlab.ui.control.Button
        BodyMassIndexBMIFitnessAnalysisPanel  matlab.ui.container.Panel
        Precaution                      matlab.ui.control.TextArea
        BackButton_3                    matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        status                          matlab.ui.control.EditField
        WaistToHeightRatioWHtRFitnessAnalysisPanel  matlab.ui.container.Panel
        BackButton_6                    matlab.ui.control.Button
        Image4                          matlab.ui.control.Image
        EditField                       matlab.ui.control.EditField
        Panel_2                         matlab.ui.container.Panel
        OverallHealthPredictionLabel    matlab.ui.control.Label
        OverallHealthPredictionEditField  matlab.ui.control.TextArea
        HowtoImprovemyHealthButton      matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        WHtRRiskLevelLamp               matlab.ui.control.Lamp
        WHtRRiskLevelLampLabel          matlab.ui.control.Label
        ShowmyWHtRFitnessAnalysisButton  matlab.ui.control.Button
        WHRRiskLevelLamp_2              matlab.ui.control.Lamp
        WHRRiskLevelLamp_2Label         matlab.ui.control.Label
        ShowmyWHRFitnessAnalysisButton_3  matlab.ui.control.Button
        ShowmyBMIFitnessAnalysisButton_2  matlab.ui.control.Button
        BMIRiskLevelLamp_2              matlab.ui.control.Lamp
        BMIRiskLevelLamp_2Label         matlab.ui.control.Label
        WaistHeightRatioWHtREditField_2  matlab.ui.control.NumericEditField
        WaistHeightRatioWHtREditField_2Label  matlab.ui.control.Label
        WaistHipRatioWHREditField_2     matlab.ui.control.NumericEditField
        WaistHipRatioWHREditField_2Label  matlab.ui.control.Label
        BodyMassIndexBMIEditField_2     matlab.ui.control.NumericEditField
        BodyMassIndexBMIEditField_2Label  matlab.ui.control.Label
        Panel_1                         matlab.ui.container.Panel
        BackButton_5                    matlab.ui.control.Button
        ShowResultButton                matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        HipDropDown                     matlab.ui.control.DropDown
        HipMeasurementEditField         matlab.ui.control.NumericEditField
        HipMeasurementEditFieldLabel    matlab.ui.control.Label
        WaistDropDown_4                 matlab.ui.control.DropDown
        WeightDropDown                  matlab.ui.control.DropDown
        HeightDropDown                  matlab.ui.control.DropDown
        WaistMeasurementEditField       matlab.ui.control.NumericEditField
        WaistMeasurementEditFieldLabel  matlab.ui.control.Label
        WeightEditField                 matlab.ui.control.NumericEditField
        WeightEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        GenderDropDown                  matlab.ui.control.DropDown
        GenderDropDownLabel             matlab.ui.control.Label
        WaistToHipRatioWHRFitnessAnalysisPanel  matlab.ui.container.Panel
        WHRStatus                       matlab.ui.control.EditField
        Image2                          matlab.ui.control.Image
        BackButton_4                    matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        Suggestionpanel                 matlab.ui.container.Panel
        BackButton_2                    matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        BMI;
        WHR;
        WHtR;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HowtoImprovemyHealthButton
        function HowtoImprovemyHealthButtonPushed(app, event)
            app.Panel_2.Visible='off';
            app.Suggestionpanel.Visible='on';
            app.TextArea.Value= sprintf("1) Start using stairs instead of lifts and elevators 2) Consuming right food. Eat some healthy food like can fish, fresh beans, and some brown rice. You can also go for some snacks like dry fruits. 3) You must aim to drink about 1.45 liters of water daily. 4) Do abs exercise and cardio exercises to release stress. 5) Eat fewer calories so that you may easily burn fat which will be very helpful in losing weight. 6) Sleep well.");
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.HeightEditField.Value=0;
            app.WeightEditField.Value=0;
            app.WaistMeasurementEditField.Value=0;
            app.HipMeasurementEditField.Value=0;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.Panel_2.Visible = "off";
            app.Panel_1.Visible='on';
        end

        % Button pushed function: ShowmyBMIFitnessAnalysisButton_2
        function ShowmyBMIFitnessAnalysisButton_2Pushed(app, event)
            app.Panel_2.Visible='off';
            app.BodyMassIndexBMIFitnessAnalysisPanel.Visible="on";
            app.WaistToHeightRatioWHtRFitnessAnalysisPanel.Visible='off';
            app.WaistToHipRatioWHRFitnessAnalysisPanel.Visible='off';
          
            if (app.BMI < 16) 
                app.Precaution.Value=['Being underweight may ' ...
                    'weaken your immune system and ' ...
                    'put you at risk of osteoporosis'];
                    app.status.Value='You are severly underweight'; 
                   
            elseif ((app.BMI> 16) && (app.BMI<= 18.5)) 
                app.Precaution.Value=['Embrace healthy eating,' ...
                    ' exercise and set action goals']; 
                app.status.Value="You are underweight";
              
            elseif ((app.BMI > 18.5) && (app.BMI<= 25)) 
                app.Precaution.Value=" You're on the right track!"; 
                app.status.Value="Your BMI is normal"; 
               
            elseif ((app.BMI > 25) && (app.BMI <= 30)) 
                app.Precaution.Value=['Embrace healthy eating,' ...
                    ' exercise and set action goals']; 
                app.status.Value='You are overweight'; 
               
             elseif ((app.BMI >30) && (app.BMI<= 35)) 
                 app.Precaution.Value="Your weight is putting you" + ...
                     " at very high risk of developing " + ...
                     "obesity-related condition";
                 app.status.Value='You are classified as obese Class I'; 
              
            elseif ((app.BMI >35) && (app. BMI<= 40)) 
                app.Precaution.Value=['Your weight is putting you at' ...
                    ' very high risk of developing' ...
                    ' obesity-related condition'];
                app.status.Value='You are classified as obese Class II'; 
                
            elseif (app.BMI> 40) 
                app.Precaution.Value=['Your weight is putting you' ...
                    ' at very high risk of developing' ...
                    ' obesity-related condition'];
                app.status.Value='You are classified as obese Class III'; 
               
            end

        end

        % Button pushed function: ShowResultButton
        function ShowResultButtonPushed(app, event)
           if((app.HeightEditField.Value==0) || (app.WeightEditField.Value ==0) || (app.WaistMeasurementEditField==0) || (app.HipMeasurementEditField==0))
               msgbox('Fill up the data');
           else
            app.Panel_2.Visible = "on";
            app.Panel_1.Visible="off";

                %%Unit conversion
                %%Height
                if strcmp(app.HeightDropDown.Value,'meters')
                    height=app.HeightEditField.Value;
                elseif strcmp(app.HeightDropDown.Value,'cm')
                    height=app.HeightEditField.Value/100;
                elseif strcmp(app.HeightDropDown.Value,'feet')
                    height=app.HeightEditField.Value/3.2808;
                else, strcmp(app.HeightDropDown.Value,'inches')
                    height=app.HeightEditField.Value*0.0254;
                end

                %%Weight
                if strcmp(app.WeightDropDown.Value,'kg')
                    weight=app.WeightEditField.Value;
                elseif strcmp(app.WeightDropDown.Value,'lbs')
                    weight=app.WeightEditField.Value*0.453592;
                else, strcmp(app.WeightDropDown.Value,'st')
                    weight=app.WeightEditField.Value*6.35029;
                end

                %% Waist
                if strcmp(app.WaistDropDown_4.Value,'inches')
                    waist = app.WaistMeasurementEditField.Value;
                else, strcmp(app.WaistDropDown_4.Value,'cm')
                    waist= app.WaistMeasurementEditField.Value*0.393701;
                end

                %% Hip
                if strcmp(app.HipDropDown.Value,'inches')
                    hip = app.HipMeasurementEditField.Value;
                else, strcmp(app.WaistDropDown_4.Value,'cm')
                    hip = app.HipMeasurementEditField.Value*0.393701;
                end

                %%BMI Calculation
                app.BMI= weight/(height^2);
                app.BodyMassIndexBMIEditField_2.Value=app.BMI;

                %% WHR Calculation
                app.WHR= waist/hip;
                app.WaistHipRatioWHREditField_2.Value=app.WHR;

                %%WHtR Calculation
                heightin = height*39.3701;
                app.WHtR=waist/heightin;
                app.WaistHeightRatioWHtREditField_2.Value=app.WHtR;

                %%RiskLamb for BMI
                if((app.BMI > 18.5) && (app.BMI<=25))
                    app.BMIRiskLevelLamp_2.Color="green";
                elseif(app.BMI < 18.5)
                    app.BMIRiskLevelLamp_2.Color="red";
                elseif(app.BMI > 25)
                    app.BMIRiskLevelLamp_2.Color="red";
                end

                %%Risklamb for WHR
                if strcmp(app.GenderDropDown, 'Male')
                    if(app.WHR > 1.0)
                        app.WHRRiskLevelLamp_2.Color='red';
                    elseif (app.WHR <=1.0)
                        app.WHRRiskLevelLamp_2.Color='green';
                    end
               else, strcmp(app.GenderDropDown, 'Female')
                   if (app.WHR >=0.85) 
                       app.WHRRiskLevelLamp_2.Color='red'; 
                   elseif (app.WHR <= 0.84) 
                       app.WHRRiskLevelLamp_2.Color="green";
                   end 
                end
           end

%% Risklamb for WHtR 
if strcmp(app.GenderDropDown.Value,'Male') 
    if ((app.WHtR>= 0.43) && (app.WHtR< 0.53)) 
        app.WHtRRiskLevelLamp.Color ='g'; 
    elseif (app.WHtR< 0.43) 
        app.WHtRRiskLevelLamp.Color ='r'; 
    elseif (app.WHtR >= 0.53)
        app.WHtRRiskLevelLamp.Color='r';
    end
end

%%Overall Health Status Prediction

if isequal(app.BMIRiskLevelLamp_2.Color,[1 0 0]) && isequal(app.WHRRiskLevelLamp_2.Color,[1 0 0]) && isequal(app.WHtRRiskLevelLamp.Color,[1 0 0]) 
    app.OverallHealthPredictionEditField.Value= "You're at higher risk category for health problems such as heart disease, Type 2 diabetes, Hypertension, Mental health issues..";
elseif isequal(app.BMIRiskLevelLamp_2.Color,[1 0 0]) && isequal(app.WHRRiskLevelLamp_2.Color,[0 1 0]) && isequal(app.WHtRRiskLevelLamp.Color,[0 1 0]) 
     app.OverallHealthPredictionEditField.Value= "Your BMI isn't normal but your normal WHR and WHtR scores indicate that, you'r  body fat is distributed in a healthier manner and that you might have a higher muscle mass. It's a positive sign, but it's always good to consult with a healthcare provider for a comprehensive assessment of your health.";
elseif isequal(app.BMIRiskLevelLamp_2.Color,[0 1 0]) && isequal(app.WHRRiskLevelLamp_2.Color,[1 0 0]) && isequal(app.WHtRRiskLevelLamp.Color,[1 0 0]) 
    app.OverallHealthPredictionEditField.Value= "Your BMI is normal but your high WHR and WHtR scores indicate that, that fat tends to accumulate around your abdomen. This type of fat distribution is often referred to as central or abdominal obesity and is associated with a higher risk of various health problems like Cardiovascular Disease and Type 2 Diabetes";
elseif isequal(app.BMIRiskLevelLamp_2.Color,[0 1 0]) && isequal(app.WHRRiskLevelLamp_2.Color,[0 1 0]) && isequal(app.WHtRRiskLevelLamp.Color,[0 1 0])
    app.OverallHealthPredictionEditField.Value= "You're at lower risk category for health problems such as heart diseas";
else
    app.OverallHealthPredictionEditField.Value="Taking care of your body is the greatest investment you can make. Embrace a healthy lifestyle today, and enjoy the benefits of a vibrant, energetic, and fulfilling life. Remember, every small step towards wellness is a giant leap towards a happier you!";
end
        end

        % Value changed function: WaistMeasurementEditField
        function WaistMeasurementEditFieldValueChanged(app, event)

        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)

        end

        % Value changed function: WeightEditField
        function WeightEditFieldValueChanged(app, event)
           
        end

        % Value changed function: HipMeasurementEditField
        function HipMeasurementEditFieldValueChanged(app, event)

        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.Suggestionpanel.Visible='off';
            app.Panel_2.Visible='on';
        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            app.Panel_2.Visible='on';
            app.BodyMassIndexBMIFitnessAnalysisPanel.Visible="off";
            app.WaistToHeightRatioWHtRFitnessAnalysisPanel.Visible='off';
            app.WaistToHipRatioWHRFitnessAnalysisPanel.Visible='off';
        end

        % Button pushed function: ShowmyWHRFitnessAnalysisButton_3
        function ShowmyWHRFitnessAnalysisButton_3Pushed(app, event)
            app.Panel_2.Visible='off';
            app.BodyMassIndexBMIFitnessAnalysisPanel.Visible="off";
            app.WaistToHeightRatioWHtRFitnessAnalysisPanel.Visible='off';
            app.WaistToHipRatioWHRFitnessAnalysisPanel.Visible='on';

            if strcmp(app.GenderDropDown.Value,'Male')
                if (app.WHR<= 0.95) 
                    app.WHRStatus.Value = "You're at low health risk"; 
                    app.TextArea_2.Value = "Your body parameters indicate you have a pear-shaped body. People with pear-shaped bodies tend to store more fat in their hips, thighs, and buttocks. This body shape is generally considered less risky in terms of heart disease compared to those who carry more weight around their abdomen (apple-shaped body).";
                elseif ((app.WHR> 0.95) && (app.MHR<= 1.0))
                    app.WHRStatus.Value ="You're at moderate health risk"; 
                    app.TextArea_2.Value="Your body parameters indicate that you have an avocado-shaped body. People with an avocado-shaped body tend to store more fat around the abdomen.Having Stronger Core and Natural Curves";
                elseif ((app.WHR > 1.0)) 
                    app.WHRStatus.Value = "You're at high health risk"; 
                    app.TextArea_2.Value= "Your body parameters says you have an apple shaped body. This is associated with a higher risk of health issues & prone to metabolic syndrome but easier to lose fat through cardio exercises and strength training. ";
                end
            else, strcmp(app.GenderDropDown.Value,'Female');
                if (app.WHR<= 0.8) 
                    app.WHRStatus.Value = "You're at low health risk"; 
                    app.TextArea_2.Value = "Your body parameters indicate you have a pear-shaped body. People with pear-shaped bodies tend to store more fat in their hips, thighs, and buttocks. This body shape is generally considered less risky in terms of heart disease compared to those who carry more weight around their abdomen (apple-shaped body).";
                elseif ((app.WHR> 0.81) && (app.WHR<= 0.84))
                    app.WHRStatus.Value ="You're at moderate health risk"; 
                    app.TextArea_2.Value="Your body parameters indicate that you have an avocado-shaped body. People with an avocado-shaped body tend to store more fat around the abdomen.Having Stronger Core and Natural Curves";
                elseif ((app.WHR > 0.85)) 
                    app.WHRStatus.Value = "You're at high health risk"; 
                    app.TextArea_2.Value= "Your body parameters says you have an apple shaped body. This is associated with a higher risk of health issues & prone to metabolic syndrome but easier to lose fat through cardio exercises and strength training.";
                end
            end

        end

        % Button pushed function: BackButton_4
        function BackButton_4Pushed(app, event)
         app.Panel_2.Visible='on';
         app.BodyMassIndexBMIFitnessAnalysisPanel.Visible="off";
         app.WaistToHeightRatioWHtRFitnessAnalysisPanel.Visible='off';
         app.WaistToHipRatioWHRFitnessAnalysisPanel.Visible='off';   
        end

        % Button pushed function: CheckYourHealthButton
        function CheckYourHealthButtonPushed(app, event)
            app.Panel_1.Visible="on";
            app.Panel_6.Visible='off';
        end

        % Button pushed function: BackButton_5
        function BackButton_5Pushed(app, event)
            app.Panel_1.Visible="off";
            app.Panel_6.Visible='on';
        end

        % Button pushed function: BackButton_6
        function BackButton_6Pushed(app, event)
         app.Panel_2.Visible='on';
         app.BodyMassIndexBMIFitnessAnalysisPanel.Visible="off";
         app.WaistToHeightRatioWHtRFitnessAnalysisPanel.Visible='off';
         app.WaistToHipRatioWHRFitnessAnalysisPanel.Visible='off';  
        end

        % Button pushed function: ShowmyWHtRFitnessAnalysisButton
        function ShowmyWHtRFitnessAnalysisButtonPushed(app, event)
         app.Panel_2.Visible='off';
         app.BodyMassIndexBMIFitnessAnalysisPanel.Visible="off";
         app.WaistToHeightRatioWHtRFitnessAnalysisPanel.Visible='on';
         app.WaistToHipRatioWHRFitnessAnalysisPanel.Visible='off';

         if strcmp(app.GenderDropDown.Value,'Male') 
             if (app.WHtR<0.35) 
                 app.EditField.Value = "You're abnormal slim";
             elseif ((app.WHtR>= 0.35) && (app.WHtR< 0.43)) 
                 app.EditField.Value = "You're extremely slim";
             elseif((app.WHtR>= 0.43) && (app.WHtR< 0.53))
                 app.EditField.Value = "You're healthy";
             elseif ((app.NHtR>= 0.53) && (app.WHtR< 0.58))
                 app.EditField.Value = "You're overweight";
             elseif ((app.WHtR>= 0.58) && (app.WHtR< 0.63))
                 app.EditField.Value = "You're Extremely overweight";
             elseif((app.WHtR>=0.63))
                 app.EditField.Value="You'r Highly Obese";
             end

         else, strcmp(app.GenderDropDown.Value,'Female') 
             if (app.WHtR<0.35) 
                 app.EditField.Value = "You're abnormal slim";
             elseif ((app.WHtR>= 0.35) && (app.WHtR< 0.42)) 
                 app.EditField.Value = "You're extremely slim";
             elseif((app.WHtR>= 0.42) && (app.WHtR< 0.49))
                 app.EditField.Value = "You're healthy";
             elseif ((app.WHtR>= 0.49) && (app.WHtR< 0.54))
                 app.EditField.Value = "You're overweight";
             elseif ((app.WHtR>= 0.54) && (app.WHtR< 0.58))
                 app.EditField.Value = "You're Extremely overweight";
             elseif((app.WHtR>=0.58))
                 app.EditField.Value="You'r Highly Obese";
             end
         end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FitnessTrackerwithMATLAB = uifigure('Visible', 'off');
            app.FitnessTrackerwithMATLAB.Name = 'MATLAB App';
            app.Panel = uipanel(app.FitnessTrackerwithMATLAB);
            app.Suggestionpanel = uipanel(app.Panel);
            app.Suggestionpanel.Title = 'Suggestion Panel';
            app.Suggestionpanel.Visible = 'off';
            app.Label = uilabel(app.Suggestionpanel);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.Suggestionpanel);
            app.TextArea.Editable = 'off';
            app.BackButton_2 = uibutton(app.Suggestionpanel, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.WaistToHipRatioWHRFitnessAnalysisPanel = uipanel(app.Panel);
            app.WaistToHipRatioWHRFitnessAnalysisPanel.Title = 'Waist To Hip Ratio (WHR) Fitness Analysis';
            app.WaistToHipRatioWHRFitnessAnalysisPanel.Visible = 'off';
            app.TextArea_2 = uitextarea(app.WaistToHipRatioWHRFitnessAnalysisPanel);
            app.BackButton_4 = uibutton(app.WaistToHipRatioWHRFitnessAnalysisPanel, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButton_4Pushed, true);
            app.BackButton_4.Text = 'Back';
            app.Image2 = uiimage(app.WaistToHipRatioWHRFitnessAnalysisPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'WHR.jpg');
            app.WHRStatus = uieditfield(app.WaistToHipRatioWHRFitnessAnalysisPanel, 'text');
            app.WHRStatus.Editable = 'off';
            app.Panel_1 = uipanel(app.Panel);
            app.Panel_1.Title = 'Enter Important Body Parameters to Track Your Fitness & Health';
            app.Panel_1.Visible = 'off';
            app.GenderDropDownLabel = uilabel(app.Panel_1);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.Panel_1);
            app.GenderDropDown.Items = {'Male', 'Female'};
            app.GenderDropDown.Value = 'Male';
            app.HeightEditFieldLabel = uilabel(app.Panel_1);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.Panel_1, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.WeightEditFieldLabel = uilabel(app.Panel_1);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.Panel_1, 'numeric');
            app.WeightEditField.ValueChangedFcn = createCallbackFcn(app, @WeightEditFieldValueChanged, true);
            app.WaistMeasurementEditFieldLabel = uilabel(app.Panel_1);
            app.WaistMeasurementEditFieldLabel.Text = 'Waist Measurement';
            app.WaistMeasurementEditField = uieditfield(app.Panel_1, 'numeric');
            app.WaistMeasurementEditField.ValueChangedFcn = createCallbackFcn(app, @WaistMeasurementEditFieldValueChanged, true);
            app.HeightDropDown = uidropdown(app.Panel_1);
            app.HeightDropDown.Items = {'meters', 'cm', 'feet', 'inch', '', ''};
            app.HeightDropDown.Value = 'cm';
            app.WeightDropDown = uidropdown(app.Panel_1);
            app.WeightDropDown.Items = {'kg', 'lbs', 'st'};
            app.WeightDropDown.Value = 'kg';
            app.WaistDropDown_4 = uidropdown(app.Panel_1);
            app.WaistDropDown_4.Items = {'inches', 'cm', ''};
            app.WaistDropDown_4.Value = 'cm';
            app.HipMeasurementEditFieldLabel = uilabel(app.Panel_1);
            app.HipMeasurementEditFieldLabel.Text = 'Hip Measurement';
            app.HipMeasurementEditField = uieditfield(app.Panel_1, 'numeric');
            app.HipMeasurementEditField.ValueChangedFcn = createCallbackFcn(app, @HipMeasurementEditFieldValueChanged, true);
            app.HipDropDown = uidropdown(app.Panel_1);
            app.HipDropDown.Items = {'inches', 'cm'};
            app.HipDropDown.Value = 'cm';
            app.ClearAllButton = uibutton(app.Panel_1, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ShowResultButton = uibutton(app.Panel_1, 'push');
            app.ShowResultButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultButtonPushed, true);
            app.ShowResultButton.Text = 'Show Result';
            app.BackButton_5 = uibutton(app.Panel_1, 'push');
            app.BackButton_5.ButtonPushedFcn = createCallbackFcn(app, @BackButton_5Pushed, true);
            app.BackButton_5.Text = 'Back';
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Visible = 'off';
            app.BodyMassIndexBMIEditField_2Label = uilabel(app.Panel_2);
            app.BodyMassIndexBMIEditField_2Label.Text = 'Body Mass Index (BMI)';
            app.BodyMassIndexBMIEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.BodyMassIndexBMIEditField_2.Editable = 'off';
            app.WaistHipRatioWHREditField_2Label = uilabel(app.Panel_2);
            app.WaistHipRatioWHREditField_2Label.Text = 'Waist Hip Ratio (WHR)';
            app.WaistHipRatioWHREditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.WaistHipRatioWHREditField_2.Editable = 'off';
            app.WaistHeightRatioWHtREditField_2Label = uilabel(app.Panel_2);
            app.WaistHeightRatioWHtREditField_2Label.Text = 'Waist Height Ratio (WHtR)';
            app.WaistHeightRatioWHtREditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.WaistHeightRatioWHtREditField_2.Editable = 'off';
            app.BMIRiskLevelLamp_2Label = uilabel(app.Panel_2);
            app.BMIRiskLevelLamp_2Label.Text = 'BMI Risk Level';
            app.BMIRiskLevelLamp_2 = uilamp(app.Panel_2);
            app.ShowmyBMIFitnessAnalysisButton_2 = uibutton(app.Panel_2, 'push');
            app.ShowmyBMIFitnessAnalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowmyBMIFitnessAnalysisButton_2Pushed, true);
            app.ShowmyBMIFitnessAnalysisButton_2.Text = 'Show my WHR Fitness Analysis';
            app.ShowmyWHRFitnessAnalysisButton_3 = uibutton(app.Panel_2, 'push');
            app.ShowmyWHRFitnessAnalysisButton_3.ButtonPushedFcn = createCallbackFcn(app, @ShowmyWHRFitnessAnalysisButton_3Pushed, true);
            app.ShowmyWHRFitnessAnalysisButton_3.Text = 'Show my WHR Fitness Analysis';
            app.WHRRiskLevelLamp_2Label = uilabel(app.Panel_2);
            app.WHRRiskLevelLamp_2Label.Text = 'WHR Risk Level';
            app.WHRRiskLevelLamp_2 = uilamp(app.Panel_2);
            app.ShowmyWHtRFitnessAnalysisButton = uibutton(app.Panel_2, 'push');
            app.ShowmyWHtRFitnessAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ShowmyWHtRFitnessAnalysisButtonPushed, true);
            app.ShowmyWHtRFitnessAnalysisButton.Text = 'Show my WHtR Fitness Analysis';
            app.WHtRRiskLevelLampLabel = uilabel(app.Panel_2);
            app.WHtRRiskLevelLampLabel.Text = 'WHtR Risk Level';
            app.WHtRRiskLevelLamp = uilamp(app.Panel_2);
            app.BackButton = uibutton(app.Panel_2, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.HowtoImprovemyHealthButton = uibutton(app.Panel_2, 'push');
            app.HowtoImprovemyHealthButton.ButtonPushedFcn = createCallbackFcn(app, @HowtoImprovemyHealthButtonPushed, true);
            app.HowtoImprovemyHealthButton.Text = 'How to Improve my Health?';
            app.OverallHealthPredictionEditField = uitextarea(app.Panel_2);
            app.OverallHealthPredictionEditField.Editable = 'off';
            app.OverallHealthPredictionLabel = uilabel(app.Panel_2);
            app.OverallHealthPredictionLabel.Text = 'Overall Health Prediction';
            app.WaistToHeightRatioWHtRFitnessAnalysisPanel = uipanel(app.Panel);
            app.WaistToHeightRatioWHtRFitnessAnalysisPanel.Title = 'Waist To Height Ratio (WHtR) Fitness Analysis';
            app.WaistToHeightRatioWHtRFitnessAnalysisPanel.Visible = 'off';
            app.EditField = uieditfield(app.WaistToHeightRatioWHtRFitnessAnalysisPanel, 'text');
            app.EditField.Editable = 'off';
            app.Image4 = uiimage(app.WaistToHeightRatioWHtRFitnessAnalysisPanel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'WHrT.jpg');
            app.BackButton_6 = uibutton(app.WaistToHeightRatioWHtRFitnessAnalysisPanel, 'push');
            app.BackButton_6.ButtonPushedFcn = createCallbackFcn(app, @BackButton_6Pushed, true);
            app.BackButton_6.Text = 'Back';
            app.BodyMassIndexBMIFitnessAnalysisPanel = uipanel(app.Panel);
            app.BodyMassIndexBMIFitnessAnalysisPanel.Title = 'Body Mass Index (BMI) Fitness Analysis';
            app.BodyMassIndexBMIFitnessAnalysisPanel.Visible = 'off';
            app.status = uieditfield(app.BodyMassIndexBMIFitnessAnalysisPanel, 'text');
            app.status.Editable = 'off';
            app.Image = uiimage(app.BodyMassIndexBMIFitnessAnalysisPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BMI.jpg');
            app.BackButton_3 = uibutton(app.BodyMassIndexBMIFitnessAnalysisPanel, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.Precaution = uitextarea(app.BodyMassIndexBMIFitnessAnalysisPanel);
            app.Panel_6 = uipanel(app.Panel);
            app.TrackYourFitnessButton = uibutton(app.Panel_6, 'push');
            app.TrackYourFitnessButton.Text = 'Track Your Fitness';
            app.CheckYourHealthButton = uibutton(app.Panel_6, 'push');
            app.CheckYourHealthButton.ButtonPushedFcn = createCallbackFcn(app, @CheckYourHealthButtonPushed, true);
            app.CheckYourHealthButton.Text = 'Check Your Health';
            app.Image3 = uiimage(app.Panel_6);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'main.jpg');
            app.TrackYourFitnesswithMATLABLabel = uilabel(app.FitnessTrackerwithMATLAB);
            app.TrackYourFitnesswithMATLABLabel.Text = 'Track Your Fitness with MATLAB ';
            % Show the figure after all components are created
            app.FitnessTrackerwithMATLAB.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FITNESSTRACKER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FitnessTrackerwithMATLAB)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FitnessTrackerwithMATLAB)
        end
    end
end